classdef Term_Project_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        SkyPlotPanel                 matlab.ui.container.Panel
        GroundTrackPanel             matlab.ui.container.Panel
        ElevationEditField           matlab.ui.control.NumericEditField
        ElevationEditFieldLabel      matlab.ui.control.Label
        AzimuthEditField             matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel        matlab.ui.control.Label
        SelectEpochEditField         matlab.ui.control.EditField
        SelectEpochEditFieldLabel    matlab.ui.control.Label
        StartEpochEditField          matlab.ui.control.EditField
        StartEpochEditFieldLabel     matlab.ui.control.Label
        SelectedGNSSEditField        matlab.ui.control.EditField
        SelectedGNSSEditFieldLabel   matlab.ui.control.Label
        TimeLinehrsSlider            matlab.ui.control.Slider
        TimeLinehrsSliderLabel       matlab.ui.control.Label
        GNSSSelectKnob               matlab.ui.control.DiscreteKnob
        GNSSSelectKnobLabel          matlab.ui.control.Label
        StationPositionLabel         matlab.ui.control.Label
        SatellitePositionLabel       matlab.ui.control.Label
        LatitiudeEditField           matlab.ui.control.NumericEditField
        LatitiudeEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField_2         matlab.ui.control.NumericEditField
        LongitudeEditField_2Label    matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        ElevationMaskEditField       matlab.ui.control.NumericEditField
        ElevationMaskEditFieldLabel  matlab.ui.control.Label
        HeightkmEditField            matlab.ui.control.NumericEditField
        HeightkmEditFieldLabel       matlab.ui.control.Label
        LatitudeEditField            matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel       matlab.ui.control.Label
        LongitudeEditField           matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel      matlab.ui.control.Label
        RunPlotButton                matlab.ui.control.Button
        TimeSelecthrsKnob            matlab.ui.control.Knob
        TimeSelecthrsKnobLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GroundTrack(app)
   
        end

        % Value changed function: GNSSSelectKnob
        function GNSSSelectKnobValueChanged(app, event)
            value = app.GNSSSelectKnob.Value;
            app.SelectedGNSSEditField.Value=value;
            if strcmp(value,"GPS")
                load("nav.mat")
                assignin("base","GPS",1)
                assignin("base","QZSS",0)
                assignin("base","BDS",0)
                app.StartEpochEditField.Value=string(datetime(nav.GPS.toc,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS'));
            elseif strcmp(value,"QZSS")
                load("nav.mat")
                assignin("base","GPS",0)
                assignin("base","QZSS",1)
                assignin("base","BDS",0)
                app.StartEpochEditField.Value=string(datetime(nav.QZSS.toc,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS'));
            else
                load("nav.mat")
                assignin("base","GPS",0)
                assignin("base","QZSS",0)
                assignin("base","BDS",1)
                app.StartEpochEditField.Value=string(datetime(nav.BDS.toc,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS'));
            end
        end

        % Value changing function: SelectedGNSSEditField
        function SelectedGNSSEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Callback function: GroundTrackPanel, RunPlotButton, SkyPlotPanel
        function RunPlotButtonPushed(app, event)

            [lon,lat,az,el,orbit_para]=sat_pos(app.TimeLinehrsSlider.Value);
            app.LongitudeEditField_2.Value=lon;
            app.LatitiudeEditField.Value=lat;
            app.AzimuthEditField.Value=az;
            app.ElevationEditField.Value=el;
             if app.TimeLinehrsSlider.Value==0
                slider_value=24;
             else
                slider_value=app.TimeLinehrsSlider.Value;
             end
             gx = geoaxes(app.GroundTrackPanel);
             for i=0:1/60:slider_value
                 [lon,lat,az,el,~]=sat_pos(app.TimeLinehrsSlider.Value);
                 plot(gx,lat,lon,'color','red','marker','*')
                 hold on
                 app.LongitudeEditField_2.Value=lon;
                 app.LatitiudeEditField.Value=lat;
                 app.AzimuthEditField.Value=az;
                 app.ElevationEditField.Value=el;
             end

             sc= satelliteScenario;
             a=orbit_para(1); e=orbit_para(2); i=orbit_para(3);
             RAAN=orbit_para(4); argprg=orbit_para(5); true_an=orbit_para(6);
             sat=satellite(sc,a,e,i,RAAN,argprg,true_an);
             groundTrack(sat);
             gs = groundStation(sc,app.LatitudeEditField.Value,app.LongitudeEditField.Value);
             play(sc)
            for i=0:1/60:slider_value
            [~,~,az,el,~]=sat_pos(i);
                for j=1:1:240
                az_data(j)=az;
                el_data(j)=el;
                end
            end
            app.SkyPlotPanel=skyplot(az_data,el_data);
            
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            value = app.LongitudeEditField.Value;
            assignin("base","lon_station",0);
            assignin("base","lon_station",value);
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            value = app.LatitudeEditField.Value;
            assignin("base","lat_station",0);
            assignin("base","lat_station",value);
        end

        % Value changed function: HeightkmEditField
        function HeightkmEditFieldValueChanged(app, event)
            value = app.HeightkmEditField.Value;
            assignin("base","hei_station",0);
            assignin("base","hei_station",value);
        end

        % Value changing function: TimeLinehrsSlider
        function TimeLinehrsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SelectEpochEditField.Value=string(datetime(app.StartEpochEditField.Value)+changingValue/24);
            app.TimeSelecthrsKnob.Value=changingValue;
            assignin("base","select_time",changingValue);
        end

        % Value changing function: TimeSelecthrsKnob
        function TimeSelecthrsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.SelectEpochEditField.Value=string(datetime(app.StartEpochEditField.Value)+changingValue/24);
            app.TimeLinehrsSlider.Value=changingValue;
            assignin("base","select_time",changingValue);
        end

        % Value changing function: StartEpochEditField
        function StartEpochEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: SelectEpochEditField
        function SelectEpochEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ElevationMaskEditField
        function ElevationMaskEditFieldValueChanged(app, event)
            value = app.ElevationMaskEditField.Value;
            assignin("base","el_mask",0);
            assignin("base","el_mask",value);
        end

        % Value changed function: LongitudeEditField_2
        function LongitudeEditField_2ValueChanged(app, event)
            value = app.LongitudeEditField_2.Value; % Satellite position

        end

        % Value changed function: LatitiudeEditField
        function LatitiudeEditFieldValueChanged(app, event)
            value = app.LatitiudeEditField.Value; % Satellite position

        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            value = app.AzimuthEditField.Value; % Satellite position

        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            value = app.ElevationEditField.Value; % Satellite position

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.LongitudeEditField.Value=0;
            app.LatitudeEditField.Value=0;
            app.HeightkmEditField.Value=0;
            app.ElevationMaskEditField.Value=0;
            app.GNSSSelectKnob.Value="GPS";
            app.SelectedGNSSEditField.Value="GPS";
            app.TimeLinehrsSlider.Value=0;
            app.TimeSelecthrsKnob.Value=0;
            load("nav.mat")
            assignin("base","GPS",1)
            assignin("base","QZSS",0)
            assignin("base","BDS",0)
            app.StartEpochEditField.Value=string(datetime(nav.GPS.toc,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS'));
        end

        % Callback function
        function SatelliteSimulationPanelButtonDown(app, event)
            
        end

        % Callback function
        function SkyPlotPanelButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSelecthrsKnobLabel = uilabel(app.UIFigure);
            app.TimeSelecthrsKnobLabel.Text = 'Time Select(hrs)';
            app.TimeSelecthrsKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeSelecthrsKnob.Limits = [0 24];
            app.TimeSelecthrsKnob.ValueChangingFcn = createCallbackFcn(app, @TimeSelecthrsKnobValueChanging, true);
            app.TimeSelecthrsKnob.MinorTicks = [];
            app.RunPlotButton = uibutton(app.UIFigure, 'push');
            app.RunPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RunPlotButtonPushed, true);
            app.RunPlotButton.Text = 'Run Plot';
            app.LongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.HeightkmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightkmEditFieldLabel.Text = 'Height(km)';
            app.HeightkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightkmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightkmEditFieldValueChanged, true);
            app.ElevationMaskEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationMaskEditFieldLabel.Text = 'Elevation Mask';
            app.ElevationMaskEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationMaskEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationMaskEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LongitudeEditField_2Label = uilabel(app.UIFigure);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.LongitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditField_2ValueChanged, true);
            app.LongitudeEditField_2.Editable = 'off';
            app.LatitiudeEditFieldLabel = uilabel(app.UIFigure);
            app.LatitiudeEditFieldLabel.Text = 'Latitiude';
            app.LatitiudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitiudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitiudeEditFieldValueChanged, true);
            app.LatitiudeEditField.Editable = 'off';
            app.SatellitePositionLabel = uilabel(app.UIFigure);
            app.SatellitePositionLabel.Text = 'Satellite Position';
            app.StationPositionLabel = uilabel(app.UIFigure);
            app.StationPositionLabel.Text = 'Station Position';
            app.GNSSSelectKnobLabel = uilabel(app.UIFigure);
            app.GNSSSelectKnobLabel.Text = 'GNSS Select';
            app.GNSSSelectKnob = uiknob(app.UIFigure, 'discrete');
            app.GNSSSelectKnob.Items = {'GPS', 'QZSS', 'BDS'};
            app.GNSSSelectKnob.ValueChangedFcn = createCallbackFcn(app, @GNSSSelectKnobValueChanged, true);
            app.GNSSSelectKnob.Value = 'GPS';
            app.TimeLinehrsSliderLabel = uilabel(app.UIFigure);
            app.TimeLinehrsSliderLabel.Text = 'Time Line(hrs)';
            app.TimeLinehrsSlider = uislider(app.UIFigure);
            app.TimeLinehrsSlider.Limits = [0 24];
            app.TimeLinehrsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24];
            app.TimeLinehrsSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.TimeLinehrsSlider.ValueChangingFcn = createCallbackFcn(app, @TimeLinehrsSliderValueChanging, true);
            app.TimeLinehrsSlider.MinorTicks = [];
            app.SelectedGNSSEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedGNSSEditFieldLabel.Text = 'Selected GNSS';
            app.SelectedGNSSEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedGNSSEditField.ValueChangingFcn = createCallbackFcn(app, @SelectedGNSSEditFieldValueChanging, true);
            app.SelectedGNSSEditField.Editable = 'off';
            app.StartEpochEditFieldLabel = uilabel(app.UIFigure);
            app.StartEpochEditFieldLabel.Text = 'Start Epoch';
            app.StartEpochEditField = uieditfield(app.UIFigure, 'text');
            app.StartEpochEditField.ValueChangingFcn = createCallbackFcn(app, @StartEpochEditFieldValueChanging, true);
            app.StartEpochEditField.Editable = 'off';
            app.SelectEpochEditFieldLabel = uilabel(app.UIFigure);
            app.SelectEpochEditFieldLabel.Text = 'Select Epoch';
            app.SelectEpochEditField = uieditfield(app.UIFigure, 'text');
            app.SelectEpochEditField.ValueChangingFcn = createCallbackFcn(app, @SelectEpochEditFieldValueChanging, true);
            app.SelectEpochEditField.Editable = 'off';
            app.AzimuthEditFieldLabel = uilabel(app.UIFigure);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.AzimuthEditField.Editable = 'off';
            app.ElevationEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.ElevationEditField.Editable = 'off';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.GroundTrackPanel.ButtonDownFcn = createCallbackFcn(app, @RunPlotButtonPushed, true);
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'Sky Plot';
            app.SkyPlotPanel.ButtonDownFcn = createCallbackFcn(app, @RunPlotButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Running Process'; ''; '1. Push ''Reset'' button'; ''; '2. Input Station Position'; ''; '3. Push ''Run Plot'' button'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Term_Project_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @GroundTrack)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end